classdef tankApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        DeneyZamannGirerekTab          matlab.ui.container.Tab
        DeneyTarihiLabel               matlab.ui.control.Label
        DeneySaatiLabel                matlab.ui.control.Label
        EditFieldTarih                 matlab.ui.control.EditField
        EditFieldSaat                  matlab.ui.control.EditField
        DeneyTrSeerekTab               matlab.ui.container.Tab
        TurLPMPanel                    matlab.ui.container.ButtonGroup
        TurLPM40Radio                  matlab.ui.control.RadioButton
        TurLPM80Radio                  matlab.ui.control.RadioButton
        TurLPM120Radio                 matlab.ui.control.RadioButton
        TurCapPanel                    matlab.ui.container.ButtonGroup
        TurCap6mmRadio                 matlab.ui.control.RadioButton
        TurCap8mmRadio                 matlab.ui.control.RadioButton
        TurCap10mmRadio                matlab.ui.control.RadioButton
        TurCap12mmRadio                matlab.ui.control.RadioButton
        TurDelikPanel                  matlab.ui.container.Panel
        TurDelik1CheckBox              matlab.ui.control.CheckBox
        TurDelik2CheckBox              matlab.ui.control.CheckBox
        TurDelik4CheckBox              matlab.ui.control.CheckBox
        TurDelik3CheckBox              matlab.ui.control.CheckBox
        TurDelik7CheckBox              matlab.ui.control.CheckBox
        TurDelik6CheckBox              matlab.ui.control.CheckBox
        TurDelik5CheckBox              matlab.ui.control.CheckBox
        TurIkiliDelikPanel             matlab.ui.container.Panel
        TurCokluDelik12CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik13CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik15CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik14CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik17CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik16CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik24CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik23CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik34CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik27CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik26CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik25CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik45CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik37CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik36CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik35CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik57CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik56CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik47CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik46CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik67CheckBox        matlab.ui.control.CheckBox
        TurUcluDelikPanel              matlab.ui.container.Panel
        TurCokluDelik126CheckBox       matlab.ui.control.CheckBox
        TurCokluDelik125CheckBox       matlab.ui.control.CheckBox
        TurCokluDelik124CheckBox       matlab.ui.control.CheckBox
        TurCokluDelik123CheckBox       matlab.ui.control.CheckBox
        TurCokluDelik127CheckBox       matlab.ui.control.CheckBox
        TurCokluDelik134CheckBox       matlab.ui.control.CheckBox
        TurCokluDelik135CheckBox       matlab.ui.control.CheckBox
        TurCokluDelik136CheckBox       matlab.ui.control.CheckBox
        TurCokluDelik137CheckBox       matlab.ui.control.CheckBox
        TurCokluDelik234CheckBox       matlab.ui.control.CheckBox
        TurCokluDelik235CheckBox       matlab.ui.control.CheckBox
        TurCokluDelik236CheckBox       matlab.ui.control.CheckBox
        TurCokluDelik237CheckBox       matlab.ui.control.CheckBox
        TurCokluDelik245CheckBox       matlab.ui.control.CheckBox
        TurCokluDelik345CheckBox       matlab.ui.control.CheckBox
        TurBogazPanel                  matlab.ui.container.ButtonGroup
        BogazliRadio                   matlab.ui.control.RadioButton
        BogazsizRadio                  matlab.ui.control.RadioButton
        TurAparatPanel                 matlab.ui.container.ButtonGroup
        AparatliRadio                  matlab.ui.control.RadioButton
        AparatsizRadio                 matlab.ui.control.RadioButton
        ExcelSatrAralnBelirleyerekTab  matlab.ui.container.Tab
        ExcelSatrBalangcLabel          matlab.ui.control.Label
        ExcelSatrSonuLabel             matlab.ui.control.Label
        EditFieldBitis                 matlab.ui.control.NumericEditField
        EditFieldBaslangic             matlab.ui.control.NumericEditField
        EditFieldDosyaIsmi             matlab.ui.control.EditField
        ExcelDosyasnnismiLabel         matlab.ui.control.Label
        xlsxLabel                      matlab.ui.control.Label
        oncekiGrafikCheckBox           matlab.ui.control.CheckBox
        Button                         matlab.ui.control.Button
        VeriPanel                      matlab.ui.container.Panel
        VeriDebiCheckBox               matlab.ui.control.CheckBox
        VeriBasinc2CheckBox            matlab.ui.control.CheckBox
        VeriBasinc1CheckBox            matlab.ui.control.CheckBox
        DeerGstericiPanel              matlab.ui.container.Panel
        VeriSetiDropDown               matlab.ui.control.DropDown
        SaniyeEditFieldLabel           matlab.ui.control.Label
        SaniyeEditField                matlab.ui.control.NumericEditField
        VeriyiGsterButton              matlab.ui.control.Button
        ListBox                        matlab.ui.control.ListBox
        StandartSapmalarLabel          matlab.ui.control.Label
        BasncagrekalibreetCheckBox     matlab.ui.control.CheckBox
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    % Data logger gets 20 data a second. So 20 rows mean one second
    
    properties (Access = private)
        startOfDatalogger = 0; % when data logger is opened
        
        startOfFilling = 0; % when filling tank is started
        
        finishOfFilling = 0; % when filling tank is finished
        
        time = string([]); % clock values of row array of datalogger
       
        timeToSave = string([]);
        clock = string([]);
        
        flowmeter = []; % flowmeter values of row array of datalogger
        
        pressure = [];

        titleValue = ''; % graph title
        
        legendValue = string([]); % legend array for graph
        
        tank_1 = []; % all tank sensor values in specific row range (flowmeter and pressures sensors)
        
        t; % time array used in graph
        
        bogazBagli;
        
        plots = gobjects;

        dataset = string([]);
        
        standard_deviation = string([]);

        delikler = string([]);
        ikiliDelikler = string([]);
        ucluDelikler = string([]); 
        
        rgbIndex = 0;
        rgb = {[1 0.843 0.706], [0.902 0.098 0.294], [0 0.51 0.784], [0.235 0.706 0.294], [0.001 0.001 0.001], [0.569 0.118 0.706], [0.999 0.882 0.098], [0.961 0.51 0.188], [0.275 0.941 0.941], [0.502 0.502 0.502], [0.502 0 0], [0.941 0.196 0.902], [0.667 0.431 0.157], [0 0 0.491], [0.491 0.491 0], [0.98 0.745 0.831], [0.863 0.745 1], [0.824 0.961 0.235], [1 0.98 0.784], [0 0.491 0.491], [0.667 1 0.765]};
        rows = ["C2", "D2", "E2", "F2", "G2", "H2"];
                
        location = 0; % Description
        
        
    end
    
    methods (Access = private)
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Here are all calibrations and other constant values and strings.
        % The calibration values can be changed acording to new experiences
        % The calibrations were done with method of heuristic approach
        function C = myPackageConstants(~)
            C.pressureSat  = 1.002; % App knows when the experiment is started and finished by looking at increase and decrease of pressure (it is when the pressure measurement is passing over 1.002 bar value.)
            C.defaultSheet = 'Sayfa1'; % It is defaul sheet name for excel files which obtained from data logger named as the date.
            C.excelExtension = '.xlsx'; % it is the excel extension which is used for this app.
            C.startBias = 20;  % It is to show data of 20/20=1 second time interval before experiment starts. 
            C.finishBias = 30; % It is to show data of 30/20=1.5 second time interval after experiment finishes. 
            C.flowStartSat = 1; % App knows when the experiment is started by looking at increase of flowmeter. The moment of when experiment is started is saturation time interval.
            C.flowFinishSat = 0.55; % Like flowStartSat, it is for experiment to be known when it is finished.
            C.startingSearch = 70; % App starts to listen to the pressure value to end experiment activity after 70/20 = 3.5 second later.
            C.searchingArea = 110; % It is magnitude of searching area for deciding to finish experiment for plot.
            C.searchingPattern = 2; % how often checking value to decide finishing plot
            C.timeInterval = 0.05; % time interval for plotting eache value
            C.amountOfRGBColor = 20; % How many RGB color is defined in the array.
            C.graphLineWidth = 1.5; % Line width for the all lines at graph.
            C.graphXLabel = 'Zaman (saniye)'; % x label of graph.
            C.basinc1Text = 'Basınç 1';
            C.basinc2Text = 'Basınç 2';
            C.deneyTuruSecerekText = 'Deney Türü Seçerek';
            C.bogazaBagliText = 'Boğaza Bağlı';
            C.deneyVerileriExcelName = 'Deney_Verileri.xlsx'; % specific experiment values sheet by sheet
            C.basincTitle = 'Basınç';
            C.debiTitle = 'Debi';
            C.stDevStart = 200; % Eliminating data when increase time at inception to not effect standard deviation.
            C.stDevFinish = 60; % Eliminating data when decrease time at finish to not effect standard deviation.
            C.separating = '------   ';
            C.excelTitles = ["Tarih (Gün.Ay.Yıl)", "Saat (Saat:Dakika:Saniye)", "Flowmetre Voltajı (Volt)", "Flowmetre Değeri (LPM)", "Basınç 1 (Bar)", "Basınç 2 (Bar)", "Basınç 1 Voltajı (Volt)", "Basınç 2 Voltajı (Volt)"];
            C.chooseDirectoryDialog = 'Excellerin Olduğu Klasörü Seçiniz';
            C.aparatliText = 'Aparatlı';
            C.aparatsizText = 'Aparatsız';
            C.matlabAppExcelName = 'matlabApp.xlsx';  % All the experiments listed
            C.chooseDataWarningText = 'Lütfen En Az Bir Veri Seçiniz.';
            C.chooseDataWarningTitle = 'Veri Seçili Değil';
            C.ExcelSatirTab = 'Excel Satır Aralığını Belirleyerek';
            C.ColumnNoOfDataSet = 6; % Excel row or data variance number of experiments
            C.noOfTekliDelik = 7; % one hole combination.
            C.noOfIkiliDelik = 21; % two holes combination.
            C.noOfUcluDelik = 15; % three holes combination.
            C.holeText = 'hole';
            C.diameterText = 'diameter';
        end
        
        function variables(app, tarih)
             %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %preparing needed variables
                [sizeOfRow, ~] = size(xlsread(append(app.location, tarih, myPackageConstants(app).excelExtension), myPackageConstants(app).defaultSheet, 'D:D'));
                %sizeOfColumn: column number/~: Row number
                %D:D -> Reading all D column
                
                %to read all clock in clock time column (B column)
                [~,app.time] = xlsread(append(app.location, tarih, myPackageConstants(app).excelExtension), myPackageConstants(app).defaultSheet, append('B2:B', string(sizeOfRow)));
                %[~,time] -> to read column as string
                
                %to read all flowmeter values in flowmeter column (D column)
                app.flowmeter = xlsread(append(app.location, tarih, myPackageConstants(app).excelExtension), myPackageConstants(app).defaultSheet, append('D2:D', string(sizeOfRow)));
                
                app.pressure = xlsread(append(app.location, tarih, myPackageConstants(app).excelExtension), myPackageConstants(app).defaultSheet, append('F2:F', string(sizeOfRow)));


        end
        
        function rowsValue(app, saat)
             %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %to find row number of when data logger is opened
                for i = 1:length(app.time)
                    if strcmp(app.time{i}, saat)
                        app.startOfDatalogger = i;
                        break
                    end
                end

                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %to find row number of when filling tank is started
                for k = app.startOfDatalogger:length(app.time)
                    if strcmp(app.TabGroup.SelectedTab.Title, myPackageConstants(app).deneyTuruSecerekText)
                        if ((~app.bogazBagli && app.flowmeter(k) > myPackageConstants(app).flowStartSat) || (app.bogazBagli && app.pressure(k) > myPackageConstants(app).pressureSat))
                            app.startOfFilling = k - myPackageConstants(app).startBias;
                            break
                        end
                    else
                        if strcmp(app.TurBogazPanel.SelectedObject.Text, myPackageConstants(app).bogazaBagliText)
                            if app.pressure(k) > myPackageConstants(app).pressureSat
                                app.startOfFilling = k - myPackageConstants(app).startBias;
                                break
                            end
                        else
                            if (~app.BasncagrekalibreetCheckBox.Value && app.flowmeter(k) > myPackageConstants(app).flowStartSat) || (app.BasncagrekalibreetCheckBox.Value && app.pressure(k) > myPackageConstants(app).pressureSat)
                                app.startOfFilling = k - myPackageConstants(app).startBias;
                                break
                            end
                        end
                    end
                end

                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %to find row number of when filling tank is finished
                for j = app.startOfFilling + myPackageConstants(app).startingSearch:length(app.time)
                    a  = true;
                    for k=1:myPackageConstants(app).searchingArea

                            if(length(app.flowmeter)>j + myPackageConstants(app).searchingPattern*k)
                                if strcmp(app.TabGroup.SelectedTab.Title, myPackageConstants(app).deneyTuruSecerekText)
                                    if ((~app.BasncagrekalibreetCheckBox.Value && ~app.bogazBagli && app.flowmeter(j + myPackageConstants(app).searchingPattern*k) > myPackageConstants(app).flowFinishSat) || (app.BasncagrekalibreetCheckBox.Value && ~app.bogazBagli && app.pressure(j + myPackageConstants(app).searchingPattern*k) > myPackageConstants(app).pressureSat) || (app.bogazBagli && app.pressure(j + myPackageConstants(app).searchingPattern*k) > myPackageConstants(app).pressureSat))
                                        a = false;
                                    end
                                else
                                    if (~app.BasncagrekalibreetCheckBox.Value && app.flowmeter(j + myPackageConstants(app).searchingPattern*k) > myPackageConstants(app).flowFinishSat ) || (app.BasncagrekalibreetCheckBox.Value && app.pressure(j + myPackageConstants(app).searchingPattern*k) > myPackageConstants(app).pressureSat)
                                       a = false;
                                    end
                                end
                            else
                                break
                            end
                    end
                    if a
                       app.finishOfFilling = j + myPackageConstants(app).finishBias;
                       break
                    end
                end 

        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % getting X and Y array for plotting graph
        function initializingGraph(app, dosyaIsmi, baslangic, bitis, identity)
            if strcmp(app.TabGroup.SelectedTab.Title, myPackageConstants(app).deneyTuruSecerekText)
                if isfile(append(app.location, myPackageConstants(app).deneyVerileriExcelName))
                    if ismember(format(app, identity), sheetnames(append(app.location, myPackageConstants(app).deneyVerileriExcelName)))
                         [sizeOfRow, ~] = size(xlsread(append(app.location, myPackageConstants(app).deneyVerileriExcelName), format(app, identity), 'D:D'));
                         app.tank_1 = xlsread(append(app.location, myPackageConstants(app).deneyVerileriExcelName), format(app, identity), append('C2:H', string(sizeOfRow)));
                         app.t=myPackageConstants(app).timeInterval:myPackageConstants(app).timeInterval:(sizeOfRow - 1)*myPackageConstants(app).timeInterval;
                    else
                         app.tank_1 = xlsread(append(app.location, dosyaIsmi, myPackageConstants(app).excelExtension), myPackageConstants(app).defaultSheet, append('C', string(baslangic),':H', string(bitis)));   
                         [~, app.timeToSave] = xlsread(append(app.location, dosyaIsmi, myPackageConstants(app).excelExtension), myPackageConstants(app).defaultSheet, append('A', string(baslangic),':A', string(bitis)));
                         [~, app.clock] = xlsread(append(app.location, dosyaIsmi, myPackageConstants(app).excelExtension), myPackageConstants(app).defaultSheet, append('B', string(baslangic),':B', string(bitis))); 
                         app.t=myPackageConstants(app).timeInterval:myPackageConstants(app).timeInterval:(bitis-baslangic + 1)*myPackageConstants(app).timeInterval;
                    end
                else
                     app.tank_1 = xlsread(append(app.location, dosyaIsmi, myPackageConstants(app).excelExtension), myPackageConstants(app).defaultSheet, append('C', string(baslangic),':H', string(bitis)));   
                     [~, app.timeToSave] = xlsread(append(app.location, dosyaIsmi, myPackageConstants(app).excelExtension), myPackageConstants(app).defaultSheet, append('A', string(baslangic),':A', string(bitis)));
                     [~, app.clock] = xlsread(append(app.location, dosyaIsmi, myPackageConstants(app).excelExtension), myPackageConstants(app).defaultSheet, append('B', string(baslangic),':B', string(bitis))); 
                     app.t=myPackageConstants(app).timeInterval:myPackageConstants(app).timeInterval:(bitis-baslangic + 1)*myPackageConstants(app).timeInterval;  
                end
            else
                app.tank_1 = xlsread(append(app.location, dosyaIsmi, myPackageConstants(app).excelExtension), myPackageConstants(app).defaultSheet, append('C', string(baslangic),':H', string(bitis)));   
                app.t=myPackageConstants(app).timeInterval:myPackageConstants(app).timeInterval:(bitis-baslangic + 1)*myPackageConstants(app).timeInterval;
            end

        end 
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % plotting pressure 1
        function plottingBasinc1(app, deney)
            if(app.VeriBasinc1CheckBox.Value)
                app.rgbIndex = app.rgbIndex + 1;
                hold(app.UIAxes,'on');
                app.titleValue = myPackageConstants(app).basinc1Text;
                app.legendValue(length(app.legendValue)+1) = append(string(length(app.legendValue)+1), '. ', deney, myPackageConstants(app).basinc1Text);
                app.plots(length(app.plots)+1) = plot(app.UIAxes, app.t, app.tank_1(:, 3), 'Color', app.rgb{mod(app.rgbIndex, myPackageConstants(app).amountOfRGBColor) + 1}, 'LineWidth', myPackageConstants(app).graphLineWidth);
                if strcmp(app.TabGroup.SelectedTab.Title, myPackageConstants(app).deneyTuruSecerekText)
                    app.dataset(length(app.dataset)+1) = string(jsonencode(app.tank_1(:, 3)));
                    stdev(app, app.tank_1(:, 3));
                end
            end
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % plotting pressure 2
        function plottingBasinc2(app, deney)
              if(app.VeriBasinc2CheckBox.Value)
                    app.rgbIndex = app.rgbIndex + 1;
                    hold(app.UIAxes,'on');
                    if(strcmp(app.titleValue, myPackageConstants(app).basinc1Text))
                        app.titleValue = append(app.titleValue, ', ', myPackageConstants(app).basinc2Text);
                    else
                        app.titleValue = myPackageConstants(app).basinc2Text;
                    end
                    app.legendValue(length(app.legendValue)+1) = append(string(length(app.legendValue)+1), '. ', deney, myPackageConstants(app).basinc2Text);
                    app.plots(length(app.plots)+1) = plot(app.UIAxes, app.t, app.tank_1(:, 4), 'Color' , app.rgb{mod(app.rgbIndex, myPackageConstants(app).amountOfRGBColor) + 1},'LineWidth', myPackageConstants(app).graphLineWidth);
                    if strcmp(app.TabGroup.SelectedTab.Title, myPackageConstants(app).deneyTuruSecerekText)
                        app.dataset(length(app.dataset)+1) = string(jsonencode(app.tank_1(:, 4)));
                        stdev(app, app.tank_1(:, 4));
                    end
              end
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % plotting debi
        function plottingDebi(app, deney)
                if(app.VeriDebiCheckBox.Value)
                    app.rgbIndex = app.rgbIndex + 1;
                    hold(app.UIAxes,'on');
                    if(contains(app.titleValue, myPackageConstants(app).basincTitle))
                        app.titleValue = append(app.titleValue, ', ', myPackageConstants(app).debiTitle);
                    else
                        app.titleValue = myPackageConstants(app).debiTitle;
                    end
                    app.legendValue(length(app.legendValue)+1) = append(string(length(app.legendValue)+1), '. ', deney, myPackageConstants(app).debiTitle);
                    app.plots(length(app.plots)+1) = plot(app.UIAxes, app.t, app.tank_1(:, 2), 'Color' , app.rgb{mod(app.rgbIndex, myPackageConstants(app).amountOfRGBColor) + 1},'LineWidth', myPackageConstants(app).graphLineWidth);
                    if strcmp(app.TabGroup.SelectedTab.Title, myPackageConstants(app).deneyTuruSecerekText)
                        app.dataset(length(app.dataset)+1) = string(jsonencode(app.tank_1(:, 2)));
                        stdev(app, app.tank_1(:, 2))
                    end
                end

        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % plotting labelling graph
        function graphLabels(app)
            title(app.UIAxes, app.titleValue);
            app.VeriSetiDropDown.Items = app.legendValue;
            xlabel(app.UIAxes, myPackageConstants(app).graphXLabel);
            ylabel(app.UIAxes, app.titleValue);
            legend(app.UIAxes, app.legendValue, 'Location','northeastoutside');
            app.ListBox.Items = app.standard_deviation;
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % resetting global variable for repeating
        function resettingGlobalVariables(app)             
        app.startOfFilling = 0; % when filling tank is started   
        app.finishOfFilling = 0; % when filling tank is finished
        app.time = string([]);       
        app.flowmeter = [];
        app.pressure = [];
        app.titleValue = ''; % Description  
        app.tank_1 = []; % Description
        app.delikler = string([]);
        app.ikiliDelikler = string([]);
        app.ucluDelikler = string([]); 
        end
        
        function stdev(app, data_basinc)
            sum = 0;
            for i = myPackageConstants(app).stDevStart:(length(data_basinc) - myPackageConstants(app).stDevFinish)
                sum = sum + data_basinc(i);
            end
            
            mean = sum/(length(data_basinc) - (myPackageConstants(app).stDevStart + myPackageConstants(app).stDevFinish));
            sum_squared = 0;
            for j = myPackageConstants(app).stDevStart:(length(data_basinc) - myPackageConstants(app).stDevFinish)
                sum_squared = sum_squared + (data_basinc(j) - mean).^2;
            end
            app.standard_deviation(length(app.standard_deviation)+1) = append(string(length(app.standard_deviation)+1), myPackageConstants(app).separating, string(sqrt(sum_squared/length(data_basinc))));
        end
        
        function resettingGraph(app)
            cla(app.UIAxes);
            app.plots = gobjects;
            app.dataset = string([]);
            app.standard_deviation = string([]);
            app.legendValue = string([]);
            app.rgbIndex = 0;
        end
        
        function saveAsExcel(app, index, row, identity)
            inputData = app.tank_1(:, index);
            excel_Writable_Data = {};
            for i = 1:size(inputData,1)
                excel_Writable_Data = [excel_Writable_Data num2cell(inputData(i,1))];
            end
            writecell(excel_Writable_Data',append(app.location, myPackageConstants(app).deneyVerileriExcelName),'Sheet',format(app, identity), 'Range', row);
            writecell(app.timeToSave,append(app.location, myPackageConstants(app).deneyVerileriExcelName),'Sheet',format(app, identity),'Range','A2');
            writecell(app.clock,append(app.location, myPackageConstants(app).deneyVerileriExcelName),'Sheet',format(app, identity),'Range','B2');
            writematrix(myPackageConstants(app).excelTitles, append(app.location, myPackageConstants(app).deneyVerileriExcelName),'Sheet',format(app, identity),'Range','A1');
        end

        function formattedText = format(~, text)
            formattedText = strrep(strrep(strrep(text,' ',''), 'diameter', 'd'), 'hole', 'h');
            return
        end
end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            while ~app.location
                app.location = append(uigetdir('', myPackageConstants(app).chooseDirectoryDialog), '/');
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if(app.VeriDebiCheckBox.Value || app.VeriBasinc1CheckBox.Value || app.VeriBasinc2CheckBox.Value)  %checking whether any data checkbox is selected or not       
                
                switch app.TabGroup.SelectedTab.Title
                    case myPackageConstants(app).deneyTuruSecerekText
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        %preparing variables
                        app.bogazBagli = strcmp(app.TurBogazPanel.SelectedObject.Text, myPackageConstants(app).bogazaBagliText);
                        resettingGlobalVariables(app);
                        dim = split(app.TurCapPanel.SelectedObject.Text);
                        bogaz = '';
                        
                        
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        %initializing interface and data array
                        checkboxArray = {app.TurDelik1CheckBox.Value, app.TurDelik2CheckBox.Value, app.TurDelik3CheckBox.Value, app.TurDelik4CheckBox.Value, app.TurDelik5CheckBox.Value, app.TurDelik6CheckBox.Value, app.TurDelik7CheckBox.Value};
                        ikiliCheckboxArray = {app.TurCokluDelik12CheckBox.Value, app.TurCokluDelik13CheckBox.Value, app.TurCokluDelik14CheckBox.Value, app.TurCokluDelik15CheckBox.Value, app.TurCokluDelik16CheckBox.Value, app.TurCokluDelik17CheckBox.Value, app.TurCokluDelik23CheckBox.Value, app.TurCokluDelik24CheckBox.Value, app.TurCokluDelik25CheckBox.Value, app.TurCokluDelik26CheckBox.Value, app.TurCokluDelik27CheckBox.Value, app.TurCokluDelik34CheckBox.Value, app.TurCokluDelik35CheckBox.Value, app.TurCokluDelik36CheckBox.Value, app.TurCokluDelik37CheckBox.Value, app.TurCokluDelik45CheckBox.Value, app.TurCokluDelik46CheckBox.Value, app.TurCokluDelik47CheckBox.Value, app.TurCokluDelik56CheckBox.Value, app.TurCokluDelik57CheckBox.Value, app.TurCokluDelik67CheckBox.Value};                   
                        ikiliCheckboxNameArray = {app.TurCokluDelik12CheckBox.Text, app.TurCokluDelik13CheckBox.Text, app.TurCokluDelik14CheckBox.Text, app.TurCokluDelik15CheckBox.Text, app.TurCokluDelik16CheckBox.Text, app.TurCokluDelik17CheckBox.Text, app.TurCokluDelik23CheckBox.Text, app.TurCokluDelik24CheckBox.Text, app.TurCokluDelik25CheckBox.Text, app.TurCokluDelik26CheckBox.Text, app.TurCokluDelik27CheckBox.Text, app.TurCokluDelik34CheckBox.Text, app.TurCokluDelik35CheckBox.Text, app.TurCokluDelik36CheckBox.Text, app.TurCokluDelik37CheckBox.Text, app.TurCokluDelik45CheckBox.Text, app.TurCokluDelik46CheckBox.Text, app.TurCokluDelik47CheckBox.Text, app.TurCokluDelik56CheckBox.Text, app.TurCokluDelik57CheckBox.Text, app.TurCokluDelik67CheckBox.Text};                   
                        ucluCheckboxArray = {app.TurCokluDelik123CheckBox.Value, app.TurCokluDelik124CheckBox.Value, app.TurCokluDelik125CheckBox.Value, app.TurCokluDelik126CheckBox.Value, app.TurCokluDelik127CheckBox.Value, app.TurCokluDelik134CheckBox.Value, app.TurCokluDelik135CheckBox.Value, app.TurCokluDelik136CheckBox.Value, app.TurCokluDelik137CheckBox.Value, app.TurCokluDelik234CheckBox.Value, app.TurCokluDelik235CheckBox.Value, app.TurCokluDelik236CheckBox.Value, app.TurCokluDelik237CheckBox.Value, app.TurCokluDelik245CheckBox.Value, app.TurCokluDelik345CheckBox.Value};
                        ucluCheckboxNameArray = {app.TurCokluDelik123CheckBox.Text, app.TurCokluDelik124CheckBox.Text, app.TurCokluDelik125CheckBox.Text, app.TurCokluDelik126CheckBox.Text, app.TurCokluDelik127CheckBox.Text, app.TurCokluDelik134CheckBox.Text, app.TurCokluDelik135CheckBox.Text, app.TurCokluDelik136CheckBox.Text, app.TurCokluDelik137CheckBox.Text, app.TurCokluDelik234CheckBox.Text, app.TurCokluDelik235CheckBox.Text, app.TurCokluDelik236CheckBox.Text, app.TurCokluDelik237CheckBox.Text, app.TurCokluDelik245CheckBox.Text, app.TurCokluDelik345CheckBox.Text};

                        
                        if strcmp(app.TurBogazPanel.SelectedObject.Text, myPackageConstants(app).bogazaBagliText)
                            if strcmp(app.TurAparatPanel.SelectedObject.Text, myPackageConstants(app).aparatliText)
                                bogaz = append(' (', myPackageConstants(app).bogazaBagliText, ')');
                            else
                                bogaz = append(' (', myPackageConstants(app).aparatsizText, ')');
                            end
                        end
                        
                        for i = 1:myPackageConstants(app).noOfUcluDelik
                            if(strcmp(string(ucluCheckboxArray(i)), 'true'))
                                app.ucluDelikler(length(app.ucluDelikler)+1) = append(myPackageConstants(app).holeText, ' ', ucluCheckboxNameArray(i));
                            end
                        end

                        
                        for i = 1:myPackageConstants(app).noOfIkiliDelik
                            if(strcmp(string(ikiliCheckboxArray(i)), 'true'))
                                app.ikiliDelikler(length(app.ikiliDelikler)+1) = append(myPackageConstants(app).holeText, ' ', ikiliCheckboxNameArray(i));
                            end
                        end
                        
                        for i = 1:myPackageConstants(app).noOfTekliDelik
                            if(strcmp(string(checkboxArray(i)), 'true'))
                                app.delikler(length(app.delikler)+1) = append(myPackageConstants(app).holeText, ' ', string(i));
                            end
                        end
                        
            
                        [~, search] = xlsread(append(app.location, myPackageConstants(app).matlabAppExcelName), myPackageConstants(app).defaultSheet, 'A:A');
                        [~, tarih] = xlsread(append(app.location, myPackageConstants(app).matlabAppExcelName), myPackageConstants(app).defaultSheet, 'C:C');
                        [~, saat] = xlsread(append(app.location, myPackageConstants(app).matlabAppExcelName), myPackageConstants(app).defaultSheet, 'B:B');
                        
                        delikSecenekleri = cat(2,app.delikler, app.ikiliDelikler, app.ucluDelikler);
                        if(app.oncekiGrafikCheckBox.Value)
                            resettingGraph(app);
                        end
                        
                        identity = '';
                        
                        for h = 1:length(delikSecenekleri)
                        
                            satir = 0;
                            
                            
                            for i = 1:length(search)
                                if(h>(length(app.delikler) + length(app.ikiliDelikler)))
                                    if(~isempty(app.ucluDelikler))
                                            if strcmp(search{i}, append(app.TurLPMPanel.SelectedObject.Text, ' - ', delikSecenekleri(h), ' - ', myPackageConstants(app).diameterText, ' ', string(dim(1)), bogaz))
                                                satir = i;
                                                identity = search{i};
                                                break
                                            end
                                    end
                                else
                                    if(h>length(app.delikler))
                                        if(~isempty(app.ikiliDelikler))
                                            if strcmp(search{i}, append(app.TurLPMPanel.SelectedObject.Text, ' - ', delikSecenekleri(h), ' - ',myPackageConstants(app).diameterText , ' ', string(dim(1)), bogaz))
                                                satir = i;
                                                identity = search{i};
                                                break
                                            end
                                        end
                                    else
                                        if(~isempty(app.delikler))
                                            if strcmp(search{i}, append(app.TurLPMPanel.SelectedObject.Text, ' - ', delikSecenekleri(h), ' - ', myPackageConstants(app).diameterText, ' ', string(dim(1)), bogaz))
                                                satir = i;
                                                identity = search{i};
                                                break
                                            end
                                        end
                                    end
                                end
                            end
                                           
                            if isfile(append(app.location, myPackageConstants(app).deneyVerileriExcelName))
                                if ~ismember(format(app, identity), sheetnames(append(app.location, myPackageConstants(app).deneyVerileriExcelName)))
                                    variables(app, string(tarih(satir))); 
                                    rowsValue(app, string(saat(satir)));
    
                                end
                            else
                                variables(app, string(tarih(satir))); 
                                rowsValue(app, string(saat(satir)));
                            end
                            
                            initializingGraph(app, string(tarih(satir)), app.startOfFilling, app.finishOfFilling, identity);  

                            if isfile(append(app.location, myPackageConstants(app).deneyVerileriExcelName))
                                if ~ismember(format(app, identity), sheetnames(append(app.location, myPackageConstants(app).deneyVerileriExcelName)))
                                    for i=1:myPackageConstants(app).ColumnNoOfDataSet
                                        saveAsExcel(app, i, app.rows{i}, identity);
                                    end
                                end
                            else
                                for i=1:myPackageConstants(app).ColumnNoOfDataSet
                                    saveAsExcel(app, i, app.rows{i}, identity);
                                end
                            end
                            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                            %to plot pressure graph
                            
                            plottingBasinc1(app, append(search(satir), ' - '));                
                            plottingBasinc2(app, append(search(satir), ' - '));                
                            plottingDebi(app, append(search(satir), ' - '));                   
                            graphLabels(app);
                            
        
                        end
                    case myPackageConstants(app).ExcelSatirTab
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        %to plot pressure graph
                        if(app.oncekiGrafikCheckBox.Value)
                            resettingGraph(app);
                        end

                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        %to plot graph
                        resettingGlobalVariables(app);
                        initializingGraph(app, app.EditFieldDosyaIsmi.Value, app.EditFieldBaslangic.Value, app.EditFieldBitis.Value, '');           
                        plottingBasinc1(app, '');           
                        plottingBasinc2(app, '');          
                        plottingDebi(app, '');      
                        graphLabels(app);
                    otherwise
                        if(app.oncekiGrafikCheckBox.Value)
                            resettingGraph(app);
                        end
                        
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        %preparing variables and data before plotting               
                        resettingGlobalVariables(app);
                        variables(app, app.EditFieldTarih.Value);      
                        rowsValue(app, app.EditFieldSaat.Value);
                       
                        
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        %to plot graph
                        initializingGraph(app, app.EditFieldTarih.Value, app.startOfFilling, app.finishOfFilling, '');  
                        plottingBasinc1(app, '');                
                        plottingBasinc2(app, '');                
                        plottingDebi(app, '');                   
                        graphLabels(app);
                end
                
                    
            else
                 warndlg(myPackageConstants(app).chooseDataWarningText, myPackageConstants(app).chooseDataWarningTitle);
            end
        end

        % Button pushed function: VeriyiGsterButton
        function VeriyiGsterButtonPushed(app, event)
            arrayhold = jsondecode(app.dataset(find(strcmp(app.legendValue, app.VeriSetiDropDown.Value))));
            datatip(app.plots(find(strcmp(app.legendValue, app.VeriSetiDropDown.Value))+1) , app.SaniyeEditField.Value, arrayhold(round((1/myPackageConstants(app).timeInterval)*app.SaniyeEditField.Value))); % System gets 20 data pattern for every second. So second value must be multipled by 1/0.05=20 to get row number.
        end

        % Selection changed function: TurBogazPanel
        function TurBogazPanelSelectionChanged(app, event)
            if strcmp(app.TurBogazPanel.SelectedObject.Text, myPackageConstants(app).bogazaBagliText)
                 app.TurAparatPanel.Enable = 'on';
            else
                app.TurAparatPanel.Enable = 'off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DeneyZamannGirerekTab = uitab(app.TabGroup);
            app.DeneyZamannGirerekTab.Title = 'Deney Zamanını Girerek';
            app.DeneyTarihiLabel = uilabel(app.DeneyZamannGirerekTab);
            app.DeneyTarihiLabel.Text = 'Deney Tarihi';
            app.DeneySaatiLabel = uilabel(app.DeneyZamannGirerekTab);
            app.DeneySaatiLabel.Text = 'Deney Saati';
            app.EditFieldTarih = uieditfield(app.DeneyZamannGirerekTab, 'text');
            app.EditFieldSaat = uieditfield(app.DeneyZamannGirerekTab, 'text');
            app.DeneyTrSeerekTab = uitab(app.TabGroup);
            app.DeneyTrSeerekTab.Title = 'Deney Türü Seçerek';
            app.TurLPMPanel = uibuttongroup(app.DeneyTrSeerekTab);
            app.TurLPMPanel.Title = 'LPM Seçiniz';
            app.TurLPM40Radio = uiradiobutton(app.TurLPMPanel);
            app.TurLPM40Radio.Text = '40 lpm';
            app.TurLPM40Radio.Value = true;
            app.TurLPM80Radio = uiradiobutton(app.TurLPMPanel);
            app.TurLPM80Radio.Text = '80 lpm';
            app.TurLPM120Radio = uiradiobutton(app.TurLPMPanel);
            app.TurLPM120Radio.Text = '120 lpm';
            app.TurCapPanel = uibuttongroup(app.DeneyTrSeerekTab);
            app.TurCapPanel.Title = 'Çap Seçiniz';
            app.TurCap6mmRadio = uiradiobutton(app.TurCapPanel);
            app.TurCap6mmRadio.Text = '6 mm';
            app.TurCap6mmRadio.Value = true;
            app.TurCap8mmRadio = uiradiobutton(app.TurCapPanel);
            app.TurCap8mmRadio.Text = '8 mm';
            app.TurCap10mmRadio = uiradiobutton(app.TurCapPanel);
            app.TurCap10mmRadio.Text = '10 mm';
            app.TurCap12mmRadio = uiradiobutton(app.TurCapPanel);
            app.TurCap12mmRadio.Text = '12 mm';
            app.TurDelikPanel = uipanel(app.DeneyTrSeerekTab);
            app.TurDelikPanel.Title = 'Delik Seçiniz';
            app.TurDelik1CheckBox = uicheckbox(app.TurDelikPanel);
            app.TurDelik1CheckBox.Text = '1. Delik';
            app.TurDelik2CheckBox = uicheckbox(app.TurDelikPanel);
            app.TurDelik2CheckBox.Text = '2. Delik';
            app.TurDelik4CheckBox = uicheckbox(app.TurDelikPanel);
            app.TurDelik4CheckBox.Text = '4. Delik';
            app.TurDelik3CheckBox = uicheckbox(app.TurDelikPanel);
            app.TurDelik3CheckBox.Text = '3. Delik';
            app.TurDelik7CheckBox = uicheckbox(app.TurDelikPanel);
            app.TurDelik7CheckBox.Text = '7.  Delik';
            app.TurDelik6CheckBox = uicheckbox(app.TurDelikPanel);
            app.TurDelik6CheckBox.Text = '6. Delik';
            app.TurDelik5CheckBox = uicheckbox(app.TurDelikPanel);
            app.TurDelik5CheckBox.Text = '5. Delik';
            app.TurIkiliDelikPanel = uipanel(app.DeneyTrSeerekTab);
            app.TurIkiliDelikPanel.Title = 'İkili Delik Seçiniz';
            app.TurCokluDelik12CheckBox = uicheckbox(app.TurIkiliDelikPanel);
            app.TurCokluDelik12CheckBox.Text = '1 + 2';
            app.TurCokluDelik13CheckBox = uicheckbox(app.TurIkiliDelikPanel);
            app.TurCokluDelik13CheckBox.Text = '1 + 3';
            app.TurCokluDelik15CheckBox = uicheckbox(app.TurIkiliDelikPanel);
            app.TurCokluDelik15CheckBox.Text = '1 + 5';
            app.TurCokluDelik14CheckBox = uicheckbox(app.TurIkiliDelikPanel);
            app.TurCokluDelik14CheckBox.Text = '1 + 4';
            app.TurCokluDelik17CheckBox = uicheckbox(app.TurIkiliDelikPanel);
            app.TurCokluDelik17CheckBox.Text = '1 + 7';
            app.TurCokluDelik16CheckBox = uicheckbox(app.TurIkiliDelikPanel);
            app.TurCokluDelik16CheckBox.Text = '1 + 6';
            app.TurCokluDelik24CheckBox = uicheckbox(app.TurIkiliDelikPanel);
            app.TurCokluDelik24CheckBox.Text = '2 + 4';
            app.TurCokluDelik23CheckBox = uicheckbox(app.TurIkiliDelikPanel);
            app.TurCokluDelik23CheckBox.Text = '2 + 3';
            app.TurCokluDelik34CheckBox = uicheckbox(app.TurIkiliDelikPanel);
            app.TurCokluDelik34CheckBox.Text = '3 + 4';
            app.TurCokluDelik27CheckBox = uicheckbox(app.TurIkiliDelikPanel);
            app.TurCokluDelik27CheckBox.Text = '2 + 7';
            app.TurCokluDelik26CheckBox = uicheckbox(app.TurIkiliDelikPanel);
            app.TurCokluDelik26CheckBox.Text = '2 + 6';
            app.TurCokluDelik25CheckBox = uicheckbox(app.TurIkiliDelikPanel);
            app.TurCokluDelik25CheckBox.Text = '2 + 5';
            app.TurCokluDelik45CheckBox = uicheckbox(app.TurIkiliDelikPanel);
            app.TurCokluDelik45CheckBox.Text = '4 + 5';
            app.TurCokluDelik37CheckBox = uicheckbox(app.TurIkiliDelikPanel);
            app.TurCokluDelik37CheckBox.Text = '3 + 7';
            app.TurCokluDelik36CheckBox = uicheckbox(app.TurIkiliDelikPanel);
            app.TurCokluDelik36CheckBox.Text = '3 + 6';
            app.TurCokluDelik35CheckBox = uicheckbox(app.TurIkiliDelikPanel);
            app.TurCokluDelik35CheckBox.Text = '3 + 5';
            app.TurCokluDelik57CheckBox = uicheckbox(app.TurIkiliDelikPanel);
            app.TurCokluDelik57CheckBox.Text = '5 + 7';
            app.TurCokluDelik56CheckBox = uicheckbox(app.TurIkiliDelikPanel);
            app.TurCokluDelik56CheckBox.Text = '5 + 6';
            app.TurCokluDelik47CheckBox = uicheckbox(app.TurIkiliDelikPanel);
            app.TurCokluDelik47CheckBox.Text = '4 + 7';
            app.TurCokluDelik46CheckBox = uicheckbox(app.TurIkiliDelikPanel);
            app.TurCokluDelik46CheckBox.Text = '4 + 6';
            app.TurCokluDelik67CheckBox = uicheckbox(app.TurIkiliDelikPanel);
            app.TurCokluDelik67CheckBox.Text = '6 + 7';
            app.TurUcluDelikPanel = uipanel(app.DeneyTrSeerekTab);
            app.TurUcluDelikPanel.Title = 'Üçlü Delik Seçiniz';
            app.TurCokluDelik126CheckBox = uicheckbox(app.TurUcluDelikPanel);
            app.TurCokluDelik126CheckBox.Text = '1 + 2 + 6';
            app.TurCokluDelik125CheckBox = uicheckbox(app.TurUcluDelikPanel);
            app.TurCokluDelik125CheckBox.Text = '1 + 2 + 5';
            app.TurCokluDelik124CheckBox = uicheckbox(app.TurUcluDelikPanel);
            app.TurCokluDelik124CheckBox.Text = '1 + 2 + 4';
            app.TurCokluDelik123CheckBox = uicheckbox(app.TurUcluDelikPanel);
            app.TurCokluDelik123CheckBox.Text = '1 + 2 + 3';
            app.TurCokluDelik127CheckBox = uicheckbox(app.TurUcluDelikPanel);
            app.TurCokluDelik127CheckBox.Text = '1 + 2 + 7';
            app.TurCokluDelik134CheckBox = uicheckbox(app.TurUcluDelikPanel);
            app.TurCokluDelik134CheckBox.Text = '1 + 3 + 4';
            app.TurCokluDelik135CheckBox = uicheckbox(app.TurUcluDelikPanel);
            app.TurCokluDelik135CheckBox.Text = '1 + 3 + 5';
            app.TurCokluDelik136CheckBox = uicheckbox(app.TurUcluDelikPanel);
            app.TurCokluDelik136CheckBox.Text = '1 + 3 + 6';
            app.TurCokluDelik137CheckBox = uicheckbox(app.TurUcluDelikPanel);
            app.TurCokluDelik137CheckBox.Text = '1 + 3 + 7';
            app.TurCokluDelik234CheckBox = uicheckbox(app.TurUcluDelikPanel);
            app.TurCokluDelik234CheckBox.Text = '2 + 3 + 4';
            app.TurCokluDelik235CheckBox = uicheckbox(app.TurUcluDelikPanel);
            app.TurCokluDelik235CheckBox.Text = '2 + 3 + 5';
            app.TurCokluDelik236CheckBox = uicheckbox(app.TurUcluDelikPanel);
            app.TurCokluDelik236CheckBox.Text = '2 + 3 + 6';
            app.TurCokluDelik237CheckBox = uicheckbox(app.TurUcluDelikPanel);
            app.TurCokluDelik237CheckBox.Text = '2 + 3 + 7';
            app.TurCokluDelik245CheckBox = uicheckbox(app.TurUcluDelikPanel);
            app.TurCokluDelik245CheckBox.Text = '2 + 4 + 5';
            app.TurCokluDelik345CheckBox = uicheckbox(app.TurUcluDelikPanel);
            app.TurCokluDelik345CheckBox.Text = '3 + 4 + 5';
            app.TurBogazPanel = uibuttongroup(app.DeneyTrSeerekTab);
            app.TurBogazPanel.SelectionChangedFcn = createCallbackFcn(app, @TurBogazPanelSelectionChanged, true);
            app.TurBogazPanel.Title = 'Boğaza Bağlı mı?';
            app.BogazliRadio = uiradiobutton(app.TurBogazPanel);
            app.BogazliRadio.Text = 'Boğaza Bağlı';
            app.BogazliRadio.Value = true;
            app.BogazsizRadio = uiradiobutton(app.TurBogazPanel);
            app.BogazsizRadio.Text = 'Boğaza Bağlı Değil';
            app.TurAparatPanel = uibuttongroup(app.DeneyTrSeerekTab);
            app.TurAparatPanel.Title = 'Aparatlı mı?';
            app.AparatliRadio = uiradiobutton(app.TurAparatPanel);
            app.AparatliRadio.Text = 'Aparatlı';
            app.AparatliRadio.Value = true;
            app.AparatsizRadio = uiradiobutton(app.TurAparatPanel);
            app.AparatsizRadio.Text = 'Aparatsız';
            app.ExcelSatrAralnBelirleyerekTab = uitab(app.TabGroup);
            app.ExcelSatrAralnBelirleyerekTab.Title = 'Excel Satır Aralığını Belirleyerek';
            app.ExcelSatrBalangcLabel = uilabel(app.ExcelSatrAralnBelirleyerekTab);
            app.ExcelSatrBalangcLabel.Text = 'Excel Satır Başlangıcı';
            app.ExcelSatrSonuLabel = uilabel(app.ExcelSatrAralnBelirleyerekTab);
            app.ExcelSatrSonuLabel.Text = 'Excel Satır Sonu';
            app.EditFieldBitis = uieditfield(app.ExcelSatrAralnBelirleyerekTab, 'numeric');
            app.EditFieldBaslangic = uieditfield(app.ExcelSatrAralnBelirleyerekTab, 'numeric');
            app.EditFieldDosyaIsmi = uieditfield(app.ExcelSatrAralnBelirleyerekTab, 'text');
            app.ExcelDosyasnnismiLabel = uilabel(app.ExcelSatrAralnBelirleyerekTab);
            app.ExcelDosyasnnismiLabel.Text = 'Excel Dosyasının ismi';
            app.xlsxLabel = uilabel(app.ExcelSatrAralnBelirleyerekTab);
            app.xlsxLabel.Text = '.xlsx';
            app.oncekiGrafikCheckBox = uicheckbox(app.UIFigure);
            app.oncekiGrafikCheckBox.Text = 'Önceki grafiği sil';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Grafiği Göster';
            app.VeriPanel = uipanel(app.UIFigure);
            app.VeriPanel.Title = 'Verileri Seçiniz';
            app.VeriDebiCheckBox = uicheckbox(app.VeriPanel);
            app.VeriDebiCheckBox.Text = 'Debi';
            app.VeriBasinc2CheckBox = uicheckbox(app.VeriPanel);
            app.VeriBasinc2CheckBox.Text = 'Basınç 2';
            app.VeriBasinc1CheckBox = uicheckbox(app.VeriPanel);
            app.VeriBasinc1CheckBox.Text = 'Basınç 1';
            app.DeerGstericiPanel = uipanel(app.UIFigure);
            app.DeerGstericiPanel.Title = 'Değer Gösterici';
            app.VeriSetiDropDown = uidropdown(app.DeerGstericiPanel);
            app.SaniyeEditFieldLabel = uilabel(app.DeerGstericiPanel);
            app.SaniyeEditFieldLabel.Text = 'Saniye:';
            app.SaniyeEditField = uieditfield(app.DeerGstericiPanel, 'numeric');
            app.VeriyiGsterButton = uibutton(app.DeerGstericiPanel, 'push');
            app.VeriyiGsterButton.ButtonPushedFcn = createCallbackFcn(app, @VeriyiGsterButtonPushed, true);
            app.VeriyiGsterButton.Text = 'Veriyi Göster';
            app.ListBox = uilistbox(app.UIFigure);
            app.StandartSapmalarLabel = uilabel(app.UIFigure);
            app.StandartSapmalarLabel.Text = 'Standart Sapmalar';
            app.BasncagrekalibreetCheckBox = uicheckbox(app.UIFigure);
            app.BasncagrekalibreetCheckBox.Text = 'Basınca göre kalibre et';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tankApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end