classdef tankApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        DeneyZamannGirerekTab          matlab.ui.container.Tab
        DeneyTarihiLabel               matlab.ui.control.Label
        DeneySaatiLabel                matlab.ui.control.Label
        EditFieldTarih                 matlab.ui.control.EditField
        PanelZamanVeri                 matlab.ui.container.Panel
        ZamanVeriDebiCheckBox          matlab.ui.control.CheckBox
        ZamanVeriBasinc2CheckBox       matlab.ui.control.CheckBox
        ZamanVeriBasinc1CheckBox       matlab.ui.control.CheckBox
        ButtonZaman                    matlab.ui.control.Button
        EditFieldSaat                  matlab.ui.control.EditField
        DeneyTrSeerekTab               matlab.ui.container.Tab
        TurLPMPanel                    matlab.ui.container.ButtonGroup
        TurLPM40Radio                  matlab.ui.control.RadioButton
        TurLPM80Radio                  matlab.ui.control.RadioButton
        TurLPM120Radio                 matlab.ui.control.RadioButton
        TurCapPanel                    matlab.ui.container.ButtonGroup
        TurCap6mmRadio                 matlab.ui.control.RadioButton
        TurCap8mmRadio                 matlab.ui.control.RadioButton
        TurCap10mmRadio                matlab.ui.control.RadioButton
        TurCap12mmRadio                matlab.ui.control.RadioButton
        TurDelikPanel                  matlab.ui.container.Panel
        TurDelik1CheckBox              matlab.ui.control.CheckBox
        TurDelik2CheckBox              matlab.ui.control.CheckBox
        TurDelik4CheckBox              matlab.ui.control.CheckBox
        TurDelik3CheckBox              matlab.ui.control.CheckBox
        TurDelik7CheckBox              matlab.ui.control.CheckBox
        TurDelik6CheckBox              matlab.ui.control.CheckBox
        TurDelik5CheckBox              matlab.ui.control.CheckBox
        TurVeriPanel                   matlab.ui.container.Panel
        TurVeriDebiCheckBox            matlab.ui.control.CheckBox
        TurVeriBasinc2CheckBox         matlab.ui.control.CheckBox
        TurVeriBasinc1CheckBox         matlab.ui.control.CheckBox
        ButtonTur                      matlab.ui.control.Button
        TurCokluDelikPanel             matlab.ui.container.Panel
        TurCokluDelik12CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik13CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik15CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik14CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik17CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik16CheckBox        matlab.ui.control.CheckBox
        ExcelSatrAralnBelirleyerekTab  matlab.ui.container.Tab
        ExcelSatrBalangcLabel          matlab.ui.control.Label
        ExcelSatrSonuLabel             matlab.ui.control.Label
        PanelSatirVeri                 matlab.ui.container.Panel
        SatirVeriDebiCheckBox          matlab.ui.control.CheckBox
        SatirVeriBasinc2CheckBox       matlab.ui.control.CheckBox
        SatirVeriBasinc1CheckBox       matlab.ui.control.CheckBox
        ButtonSatir                    matlab.ui.control.Button
        EditFieldBitis                 matlab.ui.control.NumericEditField
        EditFieldBaslangic             matlab.ui.control.NumericEditField
        EditFieldDosyaIsmi             matlab.ui.control.EditField
        ExcelDosyasnnismiLabel         matlab.ui.control.Label
        xlsxLabel                      matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        startOfDatalogger = 0; % when data logger is opened
        
        startOfFilling = 0; % when filling tank is started
        
        finishOfFilling = 0; % when filling tank is finished
        
        time = string([]);
        
        flowmeter = [];

        titleValue = ''; % Description
        
        legendValue = string([]);
        
        tank_1 = []; % Description
        
        t; % Description
    end
    
    methods (Access = private)
        
        function variables(app, tarih)
             %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %preparing needed variables
                [sizeOfRow, ~] = size(xlsread(append(tarih, '.xlsx'), 'Sayfa1', 'D:D'));
                %sizeOfColumn: column number/~: Row number
                %D:D -> Reading all D column
                
                %to read all clock in clock time column (B column)
                [~,app.time] = xlsread(append(tarih, '.xlsx'), 'Sayfa1', append('B2:B', string(sizeOfRow)));
                %[~,time] -> to read column as string
                
                %to read all flowmeter values in flowmeter column (D column)
                app.flowmeter = xlsread(append(tarih, '.xlsx'), 'Sayfa1', append('D2:D', string(sizeOfRow)));
                
        end
        
        function rowsValue(app, saat)
             %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %to find row number of when data logger is opened
                for i = 1:length(app.time)
                    if strcmp(app.time{i}, saat)
                    app.startOfDatalogger = i;
                    break
                    end
                end
                
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %to find row number of when filling tank is started
                for k = app.startOfDatalogger:length(app.time)
                    if app.flowmeter(k) > 1
                    app.startOfFilling = k - 20;
                    break
                    end
                end


                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %to find row number of when filling tank is finished
                for j = app.startOfFilling + 70:length(app.time)
                    if app.flowmeter(j) < 0.47 && app.flowmeter(j+80) < 0.47
                    app.finishOfFilling = j + 20;
                    break
                    end
                end
                
        end
        
        function initializingGraph(app, dosyaIsmi, baslangic, bitis)
             app.tank_1 = xlsread(append(dosyaIsmi, '.xlsx'), 'Sayfa1', append('D', string(baslangic),':F', string(bitis)));           
             app.t=0.05:0.05:(bitis-baslangic +1)*0.05;
        end
        
        function plottingBasinc1(app, checkbox, deney)
            if(checkbox)
                    value2 = app.tank_1(:, 2);

                    plot(app.UIAxes, app.t, value2, 'Color' , [rand rand rand]);
                    hold(app.UIAxes,'on');
                    app.titleValue = 'Basınç 1';
                    app.legendValue(length(app.legendValue)+1) = append(deney, 'Basınç 1');
            end
        end
        
        function plottingBasinc2(app, checkbox, deney)
              if(checkbox)
                    value3 = app.tank_1(:, 3);
                    plot(app.UIAxes, app.t, value3, 'Color' , [rand rand rand]);
                    hold(app.UIAxes,'on');
                    if(strcmp(app.titleValue, 'Basınç 1'))
                        app.titleValue = append(app.titleValue, ', Basınç 2');
                    else
                        app.titleValue = 'Basınç 2';
                    end
                    app.legendValue(length(app.legendValue)+1) = append(deney, 'Basınç 2');
              end
        end
        
        function plottingDebi(app, checkbox, deney)
                if(checkbox)
                    value1 = app.tank_1(:, 1);
                    plot(app.UIAxes, app.t, value1, 'Color' , [rand rand rand]);
                    hold(app.UIAxes,'on');
                    if(contains(app.titleValue, 'Basınç'))
                        app.titleValue = append(app.titleValue, ', Debi');
                    else
                        app.titleValue = 'Debi';
                    end
                    app.legendValue(length(app.legendValue)+1) = append(deney, 'Debi');
                end

        end
        
        function graphLabels(app)
            title(app.UIAxes, app.titleValue);
            xlabel(app.UIAxes, 'Zaman (saniye)');
            ylabel(app.UIAxes, app.titleValue);
            legend(app.UIAxes, app.legendValue);
        end
        
        function resettingGlobalVariables(app)             
        app.startOfFilling = 0; % when filling tank is started   
        app.finishOfFilling = 0; % when filling tank is finished
        app.time = string([]);       
        app.flowmeter = [];
        app.titleValue = ''; % Description       
        app.legendValue = string([]);    
        app.tank_1 = []; % Description
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: ButtonZaman
        function ButtonZamanPushed(app, event)
            if(app.ZamanVeriDebiCheckBox.Value || app.ZamanVeriBasinc1CheckBox.Value || app.ZamanVeriBasinc2CheckBox.Value)         
                cla(app.UIAxes);
                resettingGlobalVariables(app);
                
                variables(app, app.EditFieldTarih.Value);      
                
                rowsValue(app, app.EditFieldSaat.Value);
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %to plot pressure graph
                initializingGraph(app, app.EditFieldTarih.Value, app.startOfFilling, app.finishOfFilling);  
                plottingBasinc1(app, app.ZamanVeriBasinc1CheckBox.Value, '');                
                plottingBasinc2(app, app.ZamanVeriBasinc2CheckBox.Value, '');                
                plottingDebi(app, app.ZamanVeriDebiCheckBox.Value, '');                   
                graphLabels(app);
            else
                 warndlg('Lütfen En Az Bir Veri Seçiniz.','Veri Seçili Değil');
            end
        end

        % Button pushed function: ButtonSatir
        function ButtonSatirPushed(app, event)
          if(app.SatirVeriDebiCheckBox.Value || app.SatirVeriBasinc1CheckBox.Value || app.SatirVeriBasinc2CheckBox.Value)
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %to plot pressure graph
                cla(app.UIAxes);
                resettingGlobalVariables(app);
                initializingGraph(app, app.EditFieldDosyaIsmi.Value, app.EditFieldBaslangic.Value, app.EditFieldBitis.Value);           
                plottingBasinc1(app, app.SatirVeriBasinc1CheckBox.Value, '');           
                plottingBasinc2(app, app.SatirVeriBasinc2CheckBox.Value, '');          
                plottingDebi(app, app.SatirVeriDebiCheckBox.Value, '');      
                graphLabels(app);
          else
                 warndlg('Lütfen En Az Bir Veri Seçiniz.','Veri Seçili Değil');
          end
        end

        % Button pushed function: ButtonTur
        function ButtonTurPushed(app, event)
            if(app.TurVeriDebiCheckBox.Value || app.TurVeriBasinc1CheckBox.Value || app.TurVeriBasinc2CheckBox.Value)  
                resettingGlobalVariables(app);
                dim = split(app.TurCapPanel.SelectedObject.Text);
                
                checkboxArray = {app.TurDelik1CheckBox.Value, app.TurDelik2CheckBox.Value, app.TurDelik3CheckBox.Value, app.TurDelik4CheckBox.Value, app.TurDelik5CheckBox.Value, app.TurDelik6CheckBox.Value, app.TurDelik7CheckBox.Value};
                cokluCheckboxArray = {app.TurCokluDelik12CheckBox.Value, app.TurCokluDelik13CheckBox.Value, app.TurCokluDelik14CheckBox.Value, app.TurCokluDelik15CheckBox.Value, app.TurCokluDelik16CheckBox.Value, app.TurCokluDelik17CheckBox.Value};
                
                delikler = string([]);
                cokluDelikler = string([]);
                
                for i = 1:6
                    if(strcmp(string(cokluCheckboxArray(i)), 'true'))
                        cokluDelikler(length(cokluDelikler)+1) = append('hole 1 + ', string(i+1));
                    end
                end
                
                for i = 1:7
                    if(strcmp(string(checkboxArray(i)), 'true'))
                        delikler(length(delikler)+1) = append('hole ', string(i));
                    end
                end
                
    
                [~, search] = xlsread('matlabApp.xlsx', 'Sayfa1', 'A:A');
                [~, tarih] = xlsread('matlabApp.xlsx', 'Sayfa1', 'C:C');
                [~, saat] = xlsread('matlabApp.xlsx', 'Sayfa1', 'B:B');
                
                delikSecenekleri = cat(2,delikler, cokluDelikler);
                cla(app.UIAxes);
                for h = 1:length(delikSecenekleri)
                
                    satir = 0;

                    
                    for i = 1:length(search)
                        if(h>length(delikler))
                            if(~isempty(cokluDelikler))
                                if strcmp(search{i}, append(app.TurLPMPanel.SelectedObject.Text, ' - ', delikSecenekleri(h), ' - ',  append('diameter ', string(dim(1)))))
                                satir = i;
                                break
                                end
                            end
                        else
                            if(~isempty(delikler))
                                if strcmp(search{i}, append(app.TurLPMPanel.SelectedObject.Text, ' - ', delikSecenekleri(h), ' - ',  append('diameter ', string(dim(1)))))
                                satir = i;
                                break
                                end
                            end
                        end
                    end
                    
  
                    variables(app, string(tarih(satir)));      
                    
                    rowsValue(app, string(saat(satir)));

                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    %to plot pressure graph
                    initializingGraph(app, string(tarih(satir)), app.startOfFilling, app.finishOfFilling);  
                    plottingBasinc1(app, app.TurVeriBasinc1CheckBox.Value, append(search(satir), ' - '));                
                    plottingBasinc2(app, app.TurVeriBasinc2CheckBox.Value, append(search(satir), ' - '));                
                    plottingDebi(app, app.TurVeriDebiCheckBox.Value, append(search(satir), ' - '));                   
                    graphLabels(app); 
                end
                hold(app.UIAxes,'off');
            else
                 warndlg('Lütfen En Az Bir Veri Seçiniz.','Veri Seçili Değil');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DeneyZamannGirerekTab = uitab(app.TabGroup);
            app.DeneyZamannGirerekTab.Title = 'Deney Zamanını Girerek';
            app.DeneyTarihiLabel = uilabel(app.DeneyZamannGirerekTab);
            app.DeneyTarihiLabel.Text = 'Deney Tarihi';
            app.DeneySaatiLabel = uilabel(app.DeneyZamannGirerekTab);
            app.DeneySaatiLabel.Text = 'Deney Saati';
            app.EditFieldTarih = uieditfield(app.DeneyZamannGirerekTab, 'text');
            app.PanelZamanVeri = uipanel(app.DeneyZamannGirerekTab);
            app.PanelZamanVeri.Title = 'Verileri Seçiniz';
            app.ZamanVeriDebiCheckBox = uicheckbox(app.PanelZamanVeri);
            app.ZamanVeriDebiCheckBox.Text = 'Debi';
            app.ZamanVeriBasinc2CheckBox = uicheckbox(app.PanelZamanVeri);
            app.ZamanVeriBasinc2CheckBox.Text = 'Basınç 2';
            app.ZamanVeriBasinc1CheckBox = uicheckbox(app.PanelZamanVeri);
            app.ZamanVeriBasinc1CheckBox.Text = 'Basınç 1';
            app.ButtonZaman = uibutton(app.DeneyZamannGirerekTab, 'push');
            app.ButtonZaman.ButtonPushedFcn = createCallbackFcn(app, @ButtonZamanPushed, true);
            app.ButtonZaman.Text = 'Grafiği Göster';
            app.EditFieldSaat = uieditfield(app.DeneyZamannGirerekTab, 'text');
            app.DeneyTrSeerekTab = uitab(app.TabGroup);
            app.DeneyTrSeerekTab.Title = 'Deney Türü Seçerek';
            app.TurLPMPanel = uibuttongroup(app.DeneyTrSeerekTab);
            app.TurLPMPanel.Title = 'LPM Seçiniz';
            app.TurLPM40Radio = uiradiobutton(app.TurLPMPanel);
            app.TurLPM40Radio.Text = '40 lpm';
            app.TurLPM40Radio.Value = true;
            app.TurLPM80Radio = uiradiobutton(app.TurLPMPanel);
            app.TurLPM80Radio.Text = '80 lpm';
            app.TurLPM120Radio = uiradiobutton(app.TurLPMPanel);
            app.TurLPM120Radio.Text = '120 lpm';
            app.TurCapPanel = uibuttongroup(app.DeneyTrSeerekTab);
            app.TurCapPanel.Title = 'Çap Seçiniz';
            app.TurCap6mmRadio = uiradiobutton(app.TurCapPanel);
            app.TurCap6mmRadio.Text = '6 mm';
            app.TurCap6mmRadio.Value = true;
            app.TurCap8mmRadio = uiradiobutton(app.TurCapPanel);
            app.TurCap8mmRadio.Text = '8 mm';
            app.TurCap10mmRadio = uiradiobutton(app.TurCapPanel);
            app.TurCap10mmRadio.Text = '10 mm';
            app.TurCap12mmRadio = uiradiobutton(app.TurCapPanel);
            app.TurCap12mmRadio.Text = '12 mm';
            app.TurDelikPanel = uipanel(app.DeneyTrSeerekTab);
            app.TurDelikPanel.Title = 'Delik Seçiniz';
            app.TurDelik1CheckBox = uicheckbox(app.TurDelikPanel);
            app.TurDelik1CheckBox.Text = '1. Delik';
            app.TurDelik2CheckBox = uicheckbox(app.TurDelikPanel);
            app.TurDelik2CheckBox.Text = '2. Delik';
            app.TurDelik4CheckBox = uicheckbox(app.TurDelikPanel);
            app.TurDelik4CheckBox.Text = '4. Delik';
            app.TurDelik3CheckBox = uicheckbox(app.TurDelikPanel);
            app.TurDelik3CheckBox.Text = '3. Delik';
            app.TurDelik7CheckBox = uicheckbox(app.TurDelikPanel);
            app.TurDelik7CheckBox.Text = '7.  Delik';
            app.TurDelik6CheckBox = uicheckbox(app.TurDelikPanel);
            app.TurDelik6CheckBox.Text = '6. Delik';
            app.TurDelik5CheckBox = uicheckbox(app.TurDelikPanel);
            app.TurDelik5CheckBox.Text = '5. Delik';
            app.TurVeriPanel = uipanel(app.DeneyTrSeerekTab);
            app.TurVeriPanel.Title = 'Verileri Seçiniz';
            app.TurVeriDebiCheckBox = uicheckbox(app.TurVeriPanel);
            app.TurVeriDebiCheckBox.Text = 'Debi';
            app.TurVeriBasinc2CheckBox = uicheckbox(app.TurVeriPanel);
            app.TurVeriBasinc2CheckBox.Text = 'Basınç 2';
            app.TurVeriBasinc1CheckBox = uicheckbox(app.TurVeriPanel);
            app.TurVeriBasinc1CheckBox.Text = 'Basınç 1';
            app.ButtonTur = uibutton(app.DeneyTrSeerekTab, 'push');
            app.ButtonTur.ButtonPushedFcn = createCallbackFcn(app, @ButtonTurPushed, true);
            app.ButtonTur.Text = 'Grafiği Göster';
            app.TurCokluDelikPanel = uipanel(app.DeneyTrSeerekTab);
            app.TurCokluDelikPanel.Title = 'Çoklu Delik Seçiniz';
            app.TurCokluDelik12CheckBox = uicheckbox(app.TurCokluDelikPanel);
            app.TurCokluDelik12CheckBox.Text = '1 + 2';
            app.TurCokluDelik13CheckBox = uicheckbox(app.TurCokluDelikPanel);
            app.TurCokluDelik13CheckBox.Text = '1 + 3';
            app.TurCokluDelik15CheckBox = uicheckbox(app.TurCokluDelikPanel);
            app.TurCokluDelik15CheckBox.Text = '1 + 5';
            app.TurCokluDelik14CheckBox = uicheckbox(app.TurCokluDelikPanel);
            app.TurCokluDelik14CheckBox.Text = '1 + 4';
            app.TurCokluDelik17CheckBox = uicheckbox(app.TurCokluDelikPanel);
            app.TurCokluDelik17CheckBox.Text = '1 + 7';
            app.TurCokluDelik16CheckBox = uicheckbox(app.TurCokluDelikPanel);
            app.TurCokluDelik16CheckBox.Text = '1 + 6';
            app.ExcelSatrAralnBelirleyerekTab = uitab(app.TabGroup);
            app.ExcelSatrAralnBelirleyerekTab.Title = 'Excel Satır Aralığını Belirleyerek';
            app.ExcelSatrBalangcLabel = uilabel(app.ExcelSatrAralnBelirleyerekTab);
            app.ExcelSatrBalangcLabel.Text = 'Excel Satır Başlangıcı';
            app.ExcelSatrSonuLabel = uilabel(app.ExcelSatrAralnBelirleyerekTab);
            app.ExcelSatrSonuLabel.Text = 'Excel Satır Sonu';
            app.PanelSatirVeri = uipanel(app.ExcelSatrAralnBelirleyerekTab);
            app.PanelSatirVeri.Title = 'Verileri Seçiniz';
            app.SatirVeriDebiCheckBox = uicheckbox(app.PanelSatirVeri);
            app.SatirVeriDebiCheckBox.Text = 'Debi';
            app.SatirVeriBasinc2CheckBox = uicheckbox(app.PanelSatirVeri);
            app.SatirVeriBasinc2CheckBox.Text = 'Basınç 2';
            app.SatirVeriBasinc1CheckBox = uicheckbox(app.PanelSatirVeri);
            app.SatirVeriBasinc1CheckBox.Text = 'Basınç 1';
            app.ButtonSatir = uibutton(app.ExcelSatrAralnBelirleyerekTab, 'push');
            app.ButtonSatir.ButtonPushedFcn = createCallbackFcn(app, @ButtonSatirPushed, true);
            app.ButtonSatir.Text = 'Grafiği Göster';
            app.EditFieldBitis = uieditfield(app.ExcelSatrAralnBelirleyerekTab, 'numeric');
            app.EditFieldBaslangic = uieditfield(app.ExcelSatrAralnBelirleyerekTab, 'numeric');
            app.EditFieldDosyaIsmi = uieditfield(app.ExcelSatrAralnBelirleyerekTab, 'text');
            app.ExcelDosyasnnismiLabel = uilabel(app.ExcelSatrAralnBelirleyerekTab);
            app.ExcelDosyasnnismiLabel.Text = 'Excel Dosyasının ismi ->';
            app.xlsxLabel = uilabel(app.ExcelSatrAralnBelirleyerekTab);
            app.xlsxLabel.Text = '.xlsx';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tankApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end